classdef cuedConditioningGUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        qCondUIFigure            matlab.ui.Figure
        UITable                  matlab.ui.control.Table
        Panel                    matlab.ui.container.Panel
        TrialButton              matlab.ui.control.Button
        reverse                  matlab.ui.control.Button
        forward                  matlab.ui.control.Button
        RewardButton             matlab.ui.control.Button
        Panel_2                  matlab.ui.container.Panel
        USBPortDropDownLabel     matlab.ui.control.Label
        USBPortDropDown          matlab.ui.control.DropDown
        ConnectButton            matlab.ui.control.StateButton
        Panel_3                  matlab.ui.container.Panel
        CurrentFileLabel         matlab.ui.control.Label
        curFile                  matlab.ui.control.Label
        browseButton             matlab.ui.control.Button
        NewfileButton            matlab.ui.control.Button
        DeletefileButton         matlab.ui.control.Button
        CurrentEventLabel        matlab.ui.control.Label
        currEventTxt             matlab.ui.control.Label
        RecButton                matlab.ui.control.StateButton
        TotTrialsEditFieldLabel  matlab.ui.control.Label
        TotTrialsEditField       matlab.ui.control.NumericEditField
        StartButton              matlab.ui.control.StateButton
        check80                  matlab.ui.control.CheckBox
    end

    
    properties (Access = private)
        serialObj            % Object storing the serial communication with arduino
        defPath = [];
        currFile
    end
    
    properties (Access = public)
        cmdDone = false
        currEvent = '';
        fileID = [];
        expRunning = false;
        
        postStim = 3;
        interStim = 2;
    end
    
    methods (Access = private)
        function enableAll(app,state)
            app.USBPortDropDown.Enable = state;
            app.ConnectButton.Enable = state;
            
            app.TrialButton.Enable = state;
            app.RewardButton.Enable = state;
            app.reverse.Enable = state;
            app.forward.Enable = state;
            
            app.browseButton.Enable = state;
            app.NewfileButton.Enable = state;
            app.DeletefileButton.Enable = state;
            
        end
        
        function serialReceiver(app,~,~)
            msg = readline(app.serialObj);
            app.currEvent = msg;
            
            if isempty(msg)
                message = 'Command execution not confirmed by Arduino.';
                uialert(app.qCondUIFigure,message,'Warning','Icon','warning');
                return
            elseif strcmpi(msg, '1')
                app.cmdDone = true;
            else
                if app.RecButton.Value
                    splitMsg = strsplit(msg,'_');
                    app.UITable.Data = cat(1, splitMsg, app.UITable.Data);
                    fprintf(app.fileID, [datestr(now,'YYYYmmDD_hhMMss') ',' splitMsg{1} ',' splitMsg{2} '\n']);
                end
                app.currEventTxt.Text = msg;
            end

        end
        
        function enableRec(app,state)
            app.browseButton.Enable = state;
            app.NewfileButton.Enable = state;
            app.DeletefileButton.Enable = state;
            
            app.RecButton.Enable = state;
            app.check80.Enable = state;
            
            app.TrialButton.Enable = state;
            app.RewardButton.Enable = state;
            app.forward.Enable = state;
            app.reverse.Enable = state;
        end
        
        function onlyCue(app)
            app.cmdDone = false;
            writeline(app.serialObj,'onlyCue')
            try
                waitfor(app,'cmdDone',true)
            catch ME
                app.currEvent = '';
            end
            app.currEvent = '';
            
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % Detect COM ports and fills the drop down menu
            ports = serialportlist("available");
            if isempty(ports)
                app.USBPortDropDown.Items = "None";
                app.USBPortDropDown.Value = "None";
            else
                app.USBPortDropDown.Items = ports;
                app.USBPortDropDown.Value = ports(end);
            end
        
        end

        % Value changed function: ConnectButton
        function ConnectButtonValueChanged(app, event)
            value = app.ConnectButton.Value;
            if value
                selectedPort = app.USBPortDropDown.Value;
                app.serialObj = serialport(selectedPort,9600,'Timeout',5);
                configureCallback(app.serialObj,"terminator",@app.serialReceiver)
                
                % Update GUI
                app.ConnectButton.Text = 'Connecting...';
                app.ConnectButton.BackgroundColor = [.93 .69 .13];
                warning('off','serialport:serialport:ReadlineWarning');
                
                pause(2)                % Required for the Serial Obj to fully create
                flush(app.serialObj);   % Clear serial port device buffers
                
                % Handshake to verify the connection
                writeline(app.serialObj,'handshakeFromMATLAB');
                msg = readline(app.serialObj);
                if strcmpi(msg, 'handshakeFromArduino')
                    app.ConnectButton.Text = 'Disconnect';
                    app.ConnectButton.BackgroundColor = [.39 .83 .07];
                else
                    message = sprintf('Selected COM port was not responsive.\nNot an arduino or wrong firmware on the arduino');
                    uialert(app.qCondUIFigure,message,'Warning','Icon','warning');
                    delete(app.serialObj);
                    app.ConnectButton.Value = false;
                    app.ConnectButton.Text = 'Connect';
                    app.ConnectButton.BackgroundColor = [.96 .96 .96];
                end
                
            else
                delete(app.serialObj);
                app.ConnectButton.Text = 'Connect';
                app.ConnectButton.BackgroundColor = [.96 .96 .96];
                
            end
            warning('on','serialport:serialport:ReadlineWarning')
        end

        % Button pushed function: browseButton
        function browseButtonPushed(app, event)
            tit = 'Select a file to append data';
            if isempty(app.defPath)
                filter = '*.txt';
            else
                filter = [app.defPath '*.txt'];
            end
            [f, p] = uigetfile(filter,tit);
            figure(app.qCondUIFigure)
            
            if ~isequal(f,0)
                app.defPath = p;
                app.currFile = f;
                app.curFile.Text = f;
            end
        end

        % Button pushed function: NewfileButton
        function NewfileButtonPushed(app, event)
            tit = 'Create a new recording file';
            defName = ['qCond_' datestr(now,'YYYYmmDD-hhMMss') '.txt'];
            if ~isempty(app.defPath)
                defName = [app.defPath defName];
            end
            
            [f, p] = uiputfile('*.txt',tit,defName);
            figure(app.qCondUIFigure)
            
            if ~isequal(f,0)
                fID = fopen([p f], 'at+', 'n', 'UTF-8');
                fclose(fID);
                app.defPath = p;
                app.currFile = f;
                app.curFile.Text = f;
            end
        end

        % Button pushed function: DeletefileButton
        function DeletefileButtonPushed(app, event)
            fN = [app.defPath app.currFile];
            if exist(fN, 'file')
                delete(fN)
                app.currFile = [];
                app.curFile.Text = 'none';
            end
        end

        % Button pushed function: forward
        function forwardPushed(app, event)
            app.currEventTxt.Text = 'GUI forward';
            app.cmdDone = false;
            writeline(app.serialObj,'forward')
            enableAll(app,'off')
            try
                waitfor(app,'cmdDone',true)
            catch ME
                enableAll(app,'on');
                app.currEvent = '';
            end
            enableAll(app,'on');
            app.currEvent = '';
        end

        % Button pushed function: reverse
        function reverseButtonPushed(app, event)
            app.currEventTxt.Text = 'GUI reverse';
            app.cmdDone = false;
            writeline(app.serialObj,'reverse')
            enableAll(app,'off')
            try
                waitfor(app,'cmdDone',true)
            catch ME
                enableAll(app,'on');
                app.currEvent = '';
            end
            enableAll(app,'on');
            app.currEvent = '';
        end

        % Button pushed function: RewardButton
        function RewardButtonPushed(app, event)
            app.currEventTxt.Text = 'GUI reward';
            app.cmdDone = false;
            writeline(app.serialObj,'reward')
%             enableAll(app,'off')
            try
                waitfor(app,'cmdDone',true)
            catch ME
%                 enableAll(app,'on');
                app.currEvent = '';
            end
%             enableAll(app,'on');
            app.currEvent = '';
        end

        % Button pushed function: TrialButton
        function TrialButtonPushed(app, event)
            app.currEventTxt.Text = 'GUI trial';
            app.cmdDone = false;
            writeline(app.serialObj,'trial')
%             enableAll(app,'off')
            try
                waitfor(app,'cmdDone',true)
            catch ME
%                 enableAll(app,'on');
                app.currEvent = '';
            end
%             enableAll(app,'on');
            app.currEvent = '';
        end

        % Callback function
        function StopExpButtonPushed(app, event)
            writeline(app.serialObj,'stopExp')
            waitfor(app,'cmdDone',true)
            app.currEvent = '';
        end

        % Value changed function: RecButton
        function RecButtonValueChanged(app, event)
            value = app.RecButton.Value;
            if value
                flush(app.serialObj);
                app.browseButton.Enable = false;
                app.NewfileButton.Enable = false;
                app.DeletefileButton.Enable = false;
                try
                    app.fileID = fopen([app.defPath app.currFile],'at+', 'n', 'UTF-8');
                catch ME
                    message = 'Current file Invalid.';
                    uialert(app.qCondUIFigure,message,'Warning','Icon','warning');
                    app.RecButton.Value = false;
                    
                    app.browseButton.Enable = true;
                    app.NewfileButton.Enable = true;
                    app.DeletefileButton.Enable = true;
                end
            else
                try
                    fclose(app.fileID);
                catch ME
                end
                
                app.browseButton.Enable = true;
                app.NewfileButton.Enable = true;
                app.DeletefileButton.Enable = true;
                
                app.UITable.Data = [];
                app.fileID = [];
            end
        end

        % Value changed function: StartButton
        function StartButtonValueChanged(app, event)
            value = app.StartButton.Value;
            if value
                app.expRunning = true;
                enableRec(app,'off')
                for i=1:app.TotTrialsEditField.Value
                    if app.StartButton.Value == false
                        break
                    end
                    
                    p = rand(1);
                    if app.check80.Value
                        p = rand(1);
                        p = p<0.8;
                    else
                        p = true;
                    end
                    
                    % INTERTRIAL
                    tic
                    while toc < app.interStim+randi(5)
                        if app.StartButton.Value == false
                            break
                        end
                    end
                    %
                    if p
                        TrialButtonPushed(app, event)
                    else
                        onlyCue(app)
                    end
                    
                    tic;
                    while toc < app.postStim
                        if app.StartButton.Value == false
                            break
                        end
                    end
                end
                enableRec(app,'on')
                app.StartButton.Value = false;
                app.expRunning = false;
            else
                app.expRunning = false;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.qCondUIFigure = uifigure('Visible', 'off');
            app.qCondUIFigure.Name = 'qCond';
            app.UITable = uitable(app.qCondUIFigure);
            app.UITable.ColumnName = {'Timestamp'; 'Event'};
            app.UITable.RowName = {};
            app.Panel = uipanel(app.qCondUIFigure);
            app.Panel.Title = 'Panel';
            app.TrialButton = uibutton(app.Panel, 'push');
            app.TrialButton.ButtonPushedFcn = createCallbackFcn(app, @TrialButtonPushed, true);
            app.TrialButton.Text = 'Trial';
            app.reverse = uibutton(app.Panel, 'push');
            app.reverse.ButtonPushedFcn = createCallbackFcn(app, @reverseButtonPushed, true);
            app.reverse.Text = '<<';
            app.forward = uibutton(app.Panel, 'push');
            app.forward.ButtonPushedFcn = createCallbackFcn(app, @forwardPushed, true);
            app.forward.Text = '>>';
            app.RewardButton = uibutton(app.Panel, 'push');
            app.RewardButton.ButtonPushedFcn = createCallbackFcn(app, @RewardButtonPushed, true);
            app.RewardButton.Text = 'Reward';
            app.Panel_2 = uipanel(app.qCondUIFigure);
            app.USBPortDropDownLabel = uilabel(app.Panel_2);
            app.USBPortDropDownLabel.Text = 'USB Port';
            app.USBPortDropDown = uidropdown(app.Panel_2);
            app.ConnectButton = uibutton(app.Panel_2, 'state');
            app.ConnectButton.ValueChangedFcn = createCallbackFcn(app, @ConnectButtonValueChanged, true);
            app.ConnectButton.Text = 'Connect';
            app.Panel_3 = uipanel(app.qCondUIFigure);
            app.CurrentFileLabel = uilabel(app.Panel_3);
            app.CurrentFileLabel.Text = 'Current File:';
            app.curFile = uilabel(app.Panel_3);
            app.curFile.Text = 'none';
            app.browseButton = uibutton(app.Panel_3, 'push');
            app.browseButton.ButtonPushedFcn = createCallbackFcn(app, @browseButtonPushed, true);
            app.browseButton.Text = 'browse';
            app.NewfileButton = uibutton(app.Panel_3, 'push');
            app.NewfileButton.ButtonPushedFcn = createCallbackFcn(app, @NewfileButtonPushed, true);
            app.NewfileButton.Text = 'New file';
            app.DeletefileButton = uibutton(app.Panel_3, 'push');
            app.DeletefileButton.ButtonPushedFcn = createCallbackFcn(app, @DeletefileButtonPushed, true);
            app.DeletefileButton.Text = 'Delete file';
            app.CurrentEventLabel = uilabel(app.qCondUIFigure);
            app.CurrentEventLabel.Text = 'Current Event:';
            app.currEventTxt = uilabel(app.qCondUIFigure);
            app.currEventTxt.Text = 'none';
            app.RecButton = uibutton(app.qCondUIFigure, 'state');
            app.RecButton.ValueChangedFcn = createCallbackFcn(app, @RecButtonValueChanged, true);
            app.RecButton.Text = 'Rec';
            app.TotTrialsEditFieldLabel = uilabel(app.qCondUIFigure);
            app.TotTrialsEditFieldLabel.Text = 'Tot Trials';
            app.TotTrialsEditField = uieditfield(app.qCondUIFigure, 'numeric');
            app.TotTrialsEditField.Limits = [1 100];
            app.TotTrialsEditField.Value = 15;
            app.StartButton = uibutton(app.qCondUIFigure, 'state');
            app.StartButton.ValueChangedFcn = createCallbackFcn(app, @StartButtonValueChanged, true);
            app.StartButton.Text = 'Start';
            app.check80 = uicheckbox(app.qCondUIFigure);
            app.check80.Text = '80%';
            % Show the figure after all components are created
            app.qCondUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = cuedConditioningGUI

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.qCondUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.qCondUIFigure)
        end
    end
end